classdef ConnectToSourceWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SelectedSignalSourceEditFieldLabel  matlab.ui.control.Label
        SelectedSignalSourceEditField  matlab.ui.control.EditField
        UITable                        matlab.ui.control.Table
        SearchSourceButton             matlab.ui.control.Button
        ConnecttoSourceButton          matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
        SimulationSourceGeneratorButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainAppWindow
        SearchResult
        lsllib
    end
    
    methods (Access = private)
        
        function SearchPossibleInlet(app)
            app.SearchResult=lsl_resolve_byprop(app.lsllib,'type','EEG');
            if isempty(app.SearchResult)
                warndlg('Cannot find any signal source.','Warning')
            else
                
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Need waitbar !!!!
            app.MainAppWindow=mainapp;
            app.lsllib=lsl_loadlib();
            app.SearchPossibleInlet();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectedSignalSourceEditFieldLabel = uilabel(app.UIFigure);
            app.SelectedSignalSourceEditFieldLabel.Text = 'Selected Signal Source: ';
            app.SelectedSignalSourceEditField = uieditfield(app.UIFigure, 'text');
            app.SelectedSignalSourceEditField.Editable = 'off';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Source ID'; 'Name'; 'Channel Num'; 'Channel Format'};
            app.UITable.RowName = {};
            app.SearchSourceButton = uibutton(app.UIFigure, 'push');
            app.SearchSourceButton.Text = 'Search Source';
            app.ConnecttoSourceButton = uibutton(app.UIFigure, 'push');
            app.ConnecttoSourceButton.Text = 'Connect to Source';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.Text = 'Cancel';
            app.SimulationSourceGeneratorButton = uibutton(app.UIFigure, 'push');
            app.SimulationSourceGeneratorButton.Text = 'Simulation Source Generator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConnectToSourceWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end