classdef FeedbackInterfaceDesign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UITable                         matlab.ui.control.Table
        StepParameterPanel              matlab.ui.container.Panel
        AddStep                         matlab.ui.control.Button
        RemoveStep                      matlab.ui.control.Button
        StepTypeDropDownLabel           matlab.ui.control.Label
        StepTypeDropDown                matlab.ui.control.DropDown
        DurationsEditFieldLabel         matlab.ui.control.Label
        DurationsEditField              matlab.ui.control.NumericEditField
        FeedbackThresholdEditFieldLabel  matlab.ui.control.Label
        FeedbackThresholdEditField      matlab.ui.control.NumericEditField
        ChannelDropDownLabel            matlab.ui.control.Label
        ChannelDropDown                 matlab.ui.control.DropDown
        FeedbackFrequencyRangeHzEditFieldLabel  matlab.ui.control.Label
        FeedbackFrequencyRangeHzEditField  matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        FeedbackFrequencyRangeHzEditField_2  matlab.ui.control.NumericEditField
        FeedbackDirectionDropDownLabel  matlab.ui.control.Label
        FeedbackDirectionDropDown       matlab.ui.control.DropDown
        UpStep                          matlab.ui.control.Button
        DownStep                        matlab.ui.control.Button
        SubjectEditFieldLabel           matlab.ui.control.Label
        SubjectEditField                matlab.ui.control.EditField
        ExperimentEditFieldLabel        matlab.ui.control.Label
        ExperimentEditField             matlab.ui.control.EditField
        DataPathEditFieldLabel          matlab.ui.control.Label
        DataPathEditField               matlab.ui.control.EditField
        SaveButton                      matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp
        
        ExpDataPath
        
        InterfaceContents
    end
    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainApp=mainapp;
            ExpInfo=app.MainApp.SubjSystem.ObtainExpInfo(app.MainApp.selected_subjID,app.MainApp.selected_expID);
            app.ExpDataPath=ExpInfo.DataPath;
            app.InterfaceContents={'Order','StepType','Duration','FeedbackThreshold','Channel','FeedbackFreqMin','FeedbackFreqMax','FeedbackDirection'};
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Order'; 'Step Type'; 'Duration (s)'; 'Feedback Threshold'; 'Channel'; 'Feedback Frequency (Hz)'; 'Feedback Direction'};
            app.UITable.RowName = {};
            app.StepParameterPanel = uipanel(app.UIFigure);
            app.StepParameterPanel.Title = 'Step Parameter';
            app.AddStep = uibutton(app.StepParameterPanel, 'push');
            app.AddStep.Text = '+';
            app.RemoveStep = uibutton(app.StepParameterPanel, 'push');
            app.RemoveStep.Text = '-';
            app.StepTypeDropDownLabel = uilabel(app.StepParameterPanel);
            app.StepTypeDropDownLabel.Text = 'Step Type:';
            app.StepTypeDropDown = uidropdown(app.StepParameterPanel);
            app.StepTypeDropDown.Items = {'Open Eyes', 'Close Eyes', 'NF'};
            app.StepTypeDropDown.Value = 'Open Eyes';
            app.DurationsEditFieldLabel = uilabel(app.StepParameterPanel);
            app.DurationsEditFieldLabel.Text = 'Duration (s): ';
            app.DurationsEditField = uieditfield(app.StepParameterPanel, 'numeric');
            app.DurationsEditField.Limits = [1 Inf];
            app.DurationsEditField.Value = 1;
            app.FeedbackThresholdEditFieldLabel = uilabel(app.StepParameterPanel);
            app.FeedbackThresholdEditFieldLabel.Enable = 'off';
            app.FeedbackThresholdEditFieldLabel.Text = 'Feedback Threshold:';
            app.FeedbackThresholdEditField = uieditfield(app.StepParameterPanel, 'numeric');
            app.FeedbackThresholdEditField.Limits = [0 1];
            app.FeedbackThresholdEditField.Enable = 'off';
            app.ChannelDropDownLabel = uilabel(app.StepParameterPanel);
            app.ChannelDropDownLabel.Enable = 'off';
            app.ChannelDropDownLabel.Text = 'Channel: ';
            app.ChannelDropDown = uidropdown(app.StepParameterPanel);
            app.ChannelDropDown.Items = {'1'};
            app.ChannelDropDown.Enable = 'off';
            app.ChannelDropDown.Value = '1';
            app.FeedbackFrequencyRangeHzEditFieldLabel = uilabel(app.StepParameterPanel);
            app.FeedbackFrequencyRangeHzEditFieldLabel.Enable = 'off';
            app.FeedbackFrequencyRangeHzEditFieldLabel.Text = 'Feedback Frequency Range (Hz):';
            app.FeedbackFrequencyRangeHzEditField = uieditfield(app.StepParameterPanel, 'numeric');
            app.FeedbackFrequencyRangeHzEditField.Limits = [4 30];
            app.FeedbackFrequencyRangeHzEditField.Enable = 'off';
            app.FeedbackFrequencyRangeHzEditField.Value = 4;
            app.Label = uilabel(app.StepParameterPanel);
            app.Label.Text = '~';
            app.FeedbackFrequencyRangeHzEditField_2 = uieditfield(app.StepParameterPanel, 'numeric');
            app.FeedbackFrequencyRangeHzEditField_2.Limits = [4 30];
            app.FeedbackFrequencyRangeHzEditField_2.Enable = 'off';
            app.FeedbackFrequencyRangeHzEditField_2.Value = 8;
            app.FeedbackDirectionDropDownLabel = uilabel(app.StepParameterPanel);
            app.FeedbackDirectionDropDownLabel.Enable = 'off';
            app.FeedbackDirectionDropDownLabel.Text = 'Feedback Direction';
            app.FeedbackDirectionDropDown = uidropdown(app.StepParameterPanel);
            app.FeedbackDirectionDropDown.Items = {'Positive', 'Negative'};
            app.FeedbackDirectionDropDown.Enable = 'off';
            app.FeedbackDirectionDropDown.Value = 'Positive';
            app.UpStep = uibutton(app.StepParameterPanel, 'push');
            app.UpStep.Text = 'Up';
            app.DownStep = uibutton(app.StepParameterPanel, 'push');
            app.DownStep.Text = 'Down';
            app.SubjectEditFieldLabel = uilabel(app.UIFigure);
            app.SubjectEditFieldLabel.Text = 'Subject: ';
            app.SubjectEditField = uieditfield(app.UIFigure, 'text');
            app.SubjectEditField.Editable = 'off';
            app.ExperimentEditFieldLabel = uilabel(app.UIFigure);
            app.ExperimentEditFieldLabel.Text = 'Experiment: ';
            app.ExperimentEditField = uieditfield(app.UIFigure, 'text');
            app.ExperimentEditField.Editable = 'off';
            app.DataPathEditFieldLabel = uilabel(app.UIFigure);
            app.DataPathEditFieldLabel.Text = 'Data Path: ';
            app.DataPathEditField = uieditfield(app.UIFigure, 'text');
            app.DataPathEditField.Editable = 'off';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FeedbackInterfaceDesign(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end